classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ClassifierActivationFunctionDropDown  matlab.ui.control.DropDown
        ClassifierActivationFunctionLabel  matlab.ui.control.Label
        ChooseanarchitectureLabel  matlab.ui.control.Label
        FilterDropDown             matlab.ui.control.DropDown
        FilterDropDownLabel        matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            activation_function = convertCharsToStrings(app.ClassifierActivationFunctionDropDown.Value);
            filter = convertCharsToStrings(app.FilterDropDown.Value);
            save('interface_variables.mat', 'activation_function', 'filter');
            
            mpaper;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'None', 'Perceptron', 'Associative Memory'};
            app.FilterDropDown.Value = 'None';
            app.ChooseanarchitectureLabel = uilabel(app.UIFigure);
            app.ChooseanarchitectureLabel.Text = 'Choose an architecture:';
            app.ClassifierActivationFunctionLabel = uilabel(app.UIFigure);
            app.ClassifierActivationFunctionLabel.Text = {'Classifier '; 'Activation Function'};
            app.ClassifierActivationFunctionDropDown = uidropdown(app.UIFigure);
            app.ClassifierActivationFunctionDropDown.Items = {'Logsig', 'Hardlim', 'Purelin'};
            app.ClassifierActivationFunctionDropDown.Value = 'Logsig';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end